classdef digpot_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Digpot                      matlab.ui.Figure
        Digitalpotentiometer2ButtonGroup  matlab.ui.container.ButtonGroup
        SetvalueSlider_2            matlab.ui.control.Slider
        SetvalueSlider_2Label       matlab.ui.control.Label
        UnsetLamp_2                 matlab.ui.control.Lamp
        UnsetLamp_2Label            matlab.ui.control.Label
        SetButton_2                 matlab.ui.control.StateButton
        orwritevalueSpinner_2       matlab.ui.control.Spinner
        orwritevalueSpinner_2Label  matlab.ui.control.Label
        Digitalpotentiometer1ButtonGroup  matlab.ui.container.ButtonGroup
        UnsetLamp                   matlab.ui.control.Lamp
        UnsetLampLabel              matlab.ui.control.Label
        SetButton                   matlab.ui.control.StateButton
        orwritevalueSpinner         matlab.ui.control.Spinner
        orwritevalueSpinnerLabel    matlab.ui.control.Label
        SetvalueSlider              matlab.ui.control.Slider
        SetvalueSliderLabel         matlab.ui.control.Label
        DigitalpotentiometersLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        computer_port % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, port)
            app.computer_port = port;
        end

        % Value changed function: orwritevalueSpinner
        function orwritevalueSpinnerValueChanged(app, event)
            value = app.orwritevalueSpinner.Value;
            
            app.SetvalueSlider.Value = value;
            app.SetButton.Value = 0;
            app.UnsetLamp.Color = 'r';
            app.UnsetLampLabel.Text = 'Unset';
        end

        % Value changed function: SetvalueSlider
        function SetvalueSliderValueChanged(app, event)
            value = app.SetvalueSlider.Value;
            app.orwritevalueSpinner.Value = value;
            app.SetButton.Value = 0;
            app.UnsetLamp.Color = 'r';
            app.UnsetLampLabel.Text = 'Unset';
        end

        % Value changed function: SetButton
        function SetButtonValueChanged(app, event)
            value = app.SetButton.Value;
            
            if value == 1      
                writeline(app.computer_port,['R1,' num2str(app.orwritevalueSpinner.Value,'%.3f')]);
                resp = readline(app.computer_port);
                
                if ~contains(resp,"OK")
                    value = 0;
                    app.SetButton.Value = 0;
                    uialert(app.Digpot,'Communication failed.','Warning');
                end
            end
            
            if value == 1      
                app.UnsetLamp.Color = 'g';
                app.UnsetLampLabel.Text = 'Set';
            else
                app.UnsetLamp.Color = 'r';
                app.UnsetLampLabel.Text = 'Unset';                    
            end
        end

        % Value changed function: SetvalueSlider_2
        function SetvalueSlider_2ValueChanged(app, event)
            value = app.SetvalueSlider_2.Value;
            app.orwritevalueSpinner_2.Value = value;
            app.SetButton_2.Value = 0;
            app.UnsetLamp_2.Color = 'r';
            app.UnsetLamp_2Label.Text = 'Unset';
        end

        % Value changed function: orwritevalueSpinner_2
        function orwritevalueSpinner_2ValueChanged(app, event)
            value = app.orwritevalueSpinner_2.Value;
            
            app.SetvalueSlider_2.Value = value;
            app.SetButton_2.Value = 0;
            app.UnsetLamp_2.Color = 'r';
            app.UnsetLamp_2Label.Text = 'Unset';
        end

        % Value changed function: SetButton_2
        function SetButton_2ValueChanged(app, event)
            value = app.SetButton_2.Value;
            
            if value == 1      
                writeline(app.computer_port,['R2,' num2str(app.orwritevalueSpinner_2.Value,'%.3f')]);
                resp = readline(app.computer_port);
                
                if isstring(resp) && ~contains(resp,"OK")
                    value = 0;
                    app.SetButton_2.Value = 0;
                    uialert(app.Digpot,'Communication failed.','Warning');
                end
            end
            
            if value == 1              
                app.UnsetLamp_2.Color = 'g';
                app.UnsetLamp_2Label.Text = 'Set';
            else
                app.UnsetLamp_2.Color = 'r';
                app.UnsetLamp_2Label.Text = 'Unset';                    
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Digpot = uifigure('Visible', 'off');
            app.Digpot.Name = 'Digital potentiometers';
            app.DigitalpotentiometersLabel = uilabel(app.Digpot);
            app.DigitalpotentiometersLabel.Text = 'Digital potentiometers';
            app.Digitalpotentiometer1ButtonGroup = uibuttongroup(app.Digpot);
            app.Digitalpotentiometer1ButtonGroup.Title = 'Digital potentiometer 1';
            app.SetvalueSliderLabel = uilabel(app.Digitalpotentiometer1ButtonGroup);
            app.SetvalueSliderLabel.Text = 'Set value';
            app.SetvalueSlider = uislider(app.Digitalpotentiometer1ButtonGroup);
            app.SetvalueSlider.Limits = [0 1];
            app.SetvalueSlider.MajorTicks = [0 0.125 0.25 0.375 0.5 0.625 0.75 0.875 1];
            app.SetvalueSlider.MajorTickLabels = {'0', '0.125', '0.25', '0.375', '0.5', '0.625', '0.75', '0.875', '1'};
            app.SetvalueSlider.ValueChangedFcn = createCallbackFcn(app, @SetvalueSliderValueChanged, true);
            app.orwritevalueSpinnerLabel = uilabel(app.Digitalpotentiometer1ButtonGroup);
            app.orwritevalueSpinnerLabel.Text = 'or write value';
            app.orwritevalueSpinner = uispinner(app.Digitalpotentiometer1ButtonGroup);
            app.orwritevalueSpinner.Step = 0.1;
            app.orwritevalueSpinner.Limits = [0 1];
            app.orwritevalueSpinner.ValueDisplayFormat = '%1.4g';
            app.orwritevalueSpinner.ValueChangedFcn = createCallbackFcn(app, @orwritevalueSpinnerValueChanged, true);
            app.SetButton = uibutton(app.Digitalpotentiometer1ButtonGroup, 'state');
            app.SetButton.ValueChangedFcn = createCallbackFcn(app, @SetButtonValueChanged, true);
            app.SetButton.Text = 'Set';
            app.UnsetLampLabel = uilabel(app.Digitalpotentiometer1ButtonGroup);
            app.UnsetLampLabel.Text = 'Unset';
            app.UnsetLamp = uilamp(app.Digitalpotentiometer1ButtonGroup);
            app.Digitalpotentiometer2ButtonGroup = uibuttongroup(app.Digpot);
            app.Digitalpotentiometer2ButtonGroup.Title = 'Digital potentiometer 2';
            app.orwritevalueSpinner_2Label = uilabel(app.Digitalpotentiometer2ButtonGroup);
            app.orwritevalueSpinner_2Label.Text = 'or write value';
            app.orwritevalueSpinner_2 = uispinner(app.Digitalpotentiometer2ButtonGroup);
            app.orwritevalueSpinner_2.Step = 0.1;
            app.orwritevalueSpinner_2.Limits = [0 1];
            app.orwritevalueSpinner_2.ValueDisplayFormat = '%1.4g';
            app.orwritevalueSpinner_2.ValueChangedFcn = createCallbackFcn(app, @orwritevalueSpinner_2ValueChanged, true);
            app.SetButton_2 = uibutton(app.Digitalpotentiometer2ButtonGroup, 'state');
            app.SetButton_2.ValueChangedFcn = createCallbackFcn(app, @SetButton_2ValueChanged, true);
            app.SetButton_2.Text = 'Set';
            app.UnsetLamp_2Label = uilabel(app.Digitalpotentiometer2ButtonGroup);
            app.UnsetLamp_2Label.Text = 'Unset';
            app.UnsetLamp_2 = uilamp(app.Digitalpotentiometer2ButtonGroup);
            app.SetvalueSlider_2Label = uilabel(app.Digitalpotentiometer2ButtonGroup);
            app.SetvalueSlider_2Label.Text = 'Set value';
            app.SetvalueSlider_2 = uislider(app.Digitalpotentiometer2ButtonGroup);
            app.SetvalueSlider_2.Limits = [0 1];
            app.SetvalueSlider_2.MajorTicks = [0 0.125 0.25 0.375 0.5 0.625 0.75 0.875 1];
            app.SetvalueSlider_2.MajorTickLabels = {'0', '0.125', '0.25', '0.375', '0.5', '0.625', '0.75', '0.875', '1'};
            app.SetvalueSlider_2.ValueChangedFcn = createCallbackFcn(app, @SetvalueSlider_2ValueChanged, true);
            % Show the figure after all components are created
            app.Digpot.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = digpot_v1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Digpot)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Digpot)
        end
    end
end