classdef hybrid_computer_app_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HybridcomputerAPPmenuUIFigure  matlab.ui.Figure
        ConnectionButtonGroup          matlab.ui.container.ButtonGroup
        ConnectButton                  matlab.ui.control.Button
        SelectedCOMportDropDownLabel   matlab.ui.control.Label
        SelectedCOMportDropDown        matlab.ui.control.DropDown
        LoadCOMportsButton             matlab.ui.control.Button
        DisconnectedLampLabel          matlab.ui.control.Label
        DisconnectedLamp               matlab.ui.control.Lamp
        HybridcomputerAPPLabel         matlab.ui.control.Label
        FeaturesButtonGroup            matlab.ui.container.ButtonGroup
        PWLblockButton                 matlab.ui.control.Button
        DigitalpotentiometersButton    matlab.ui.control.Button
        ICblockButton                  matlab.ui.control.Button
        RecordingButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        computer_port % Description
    end
    
    properties (Access = public)
    end
    
    methods (Access = private)
        
        
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.computer_port = NaN;
        end

        % Button pushed function: LoadCOMportsButton
        function LoadCOMportsButtonPushed(app, event)
            
            app.SelectedCOMportDropDown.Items = serialportlist;
            
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            if app.ConnectButton.Text == "Connect"
                              
                app.computer_port = serialport(app.SelectedCOMportDropDown.Value,115200,'Timeout',1);
                configureTerminator(app.computer_port,"LF");
                
                writeline(app.computer_port,"CON");
                
                resp = readline(app.computer_port);
                
                if isstring(resp) && contains(resp,"OK")
                    app.ConnectButton.Text = "Disconnect";
                    app.DisconnectedLamp.Color = "g";
                    app.DisconnectedLampLabel.Text = "Connected";
                else
                    app.computer_port = [];
                    uialert(app.HybridcomputerAPPmenuUIFigure,'No response.','Warning');
                end
                
            elseif app.ConnectButton.Text == "Disconnect"
                
                writeline(app.computer_port,"DIS");

                app.computer_port = [];
                app.ConnectButton.Text = "Connect";
                app.DisconnectedLamp.Color = "r";
                app.DisconnectedLampLabel.Text = "Disconnected";

            end
        end

        % Button pushed function: RecordingButton
        function RecordingButtonPushed(app, event)
            
            if isobject(app.computer_port)
                recording_v1(app.computer_port);
            else
                uialert(app.HybridcomputerAPPmenuUIFigure,'Hybrid computer is not connected.','Warning');
            end
        end

        % Button pushed function: PWLblockButton
        function PWLblockButtonPushed(app, event)
                        
            if isobject(app.computer_port)
                pwlblock_v1(app.computer_port);
            else
                uialert(app.HybridcomputerAPPmenuUIFigure,'Hybrid computer is not connected.','Warning');
            end
        end

        % Button pushed function: DigitalpotentiometersButton
        function DigitalpotentiometersButtonPushed(app, event)
            
            if isobject(app.computer_port)
                digpot_v1(app.computer_port);
            else
                uialert(app.HybridcomputerAPPmenuUIFigure,'Hybrid computer is not connected.','Warning');
            end
        end

        % Button pushed function: ICblockButton
        function ICblockButtonPushed(app, event)
            
            if isobject(app.computer_port)
                icblock_v1(app.computer_port);
            else
                uialert(app.HybridcomputerAPPmenuUIFigure,'Hybrid computer is not connected.','Warning');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HybridcomputerAPPmenuUIFigure = uifigure('Visible', 'off');
            app.HybridcomputerAPPmenuUIFigure.Name = 'Hybrid computer APP - menu';
            app.FeaturesButtonGroup = uibuttongroup(app.HybridcomputerAPPmenuUIFigure);
            app.FeaturesButtonGroup.Title = 'Features';
            app.RecordingButton = uibutton(app.FeaturesButtonGroup, 'push');
            app.RecordingButton.ButtonPushedFcn = createCallbackFcn(app, @RecordingButtonPushed, true);
            app.RecordingButton.Text = 'Recording';
            app.ICblockButton = uibutton(app.FeaturesButtonGroup, 'push');
            app.ICblockButton.ButtonPushedFcn = createCallbackFcn(app, @ICblockButtonPushed, true);
            app.ICblockButton.Text = 'IC block';
            app.DigitalpotentiometersButton = uibutton(app.FeaturesButtonGroup, 'push');
            app.DigitalpotentiometersButton.ButtonPushedFcn = createCallbackFcn(app, @DigitalpotentiometersButtonPushed, true);
            app.DigitalpotentiometersButton.Text = {'Digital'; 'potentiometers'};
            app.PWLblockButton = uibutton(app.FeaturesButtonGroup, 'push');
            app.PWLblockButton.ButtonPushedFcn = createCallbackFcn(app, @PWLblockButtonPushed, true);
            app.PWLblockButton.Text = 'PWL block';
            app.HybridcomputerAPPLabel = uilabel(app.HybridcomputerAPPmenuUIFigure);
            app.HybridcomputerAPPLabel.Text = 'Hybrid computer APP';
            app.DisconnectedLamp = uilamp(app.HybridcomputerAPPmenuUIFigure);
            app.DisconnectedLampLabel = uilabel(app.HybridcomputerAPPmenuUIFigure);
            app.DisconnectedLampLabel.Text = 'Disconnected';
            app.ConnectionButtonGroup = uibuttongroup(app.HybridcomputerAPPmenuUIFigure);
            app.ConnectionButtonGroup.Title = 'Connection';
            app.LoadCOMportsButton = uibutton(app.ConnectionButtonGroup, 'push');
            app.LoadCOMportsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCOMportsButtonPushed, true);
            app.LoadCOMportsButton.Text = 'Load COM ports';
            app.SelectedCOMportDropDown = uidropdown(app.ConnectionButtonGroup);
            app.SelectedCOMportDropDown.Items = {};
            app.SelectedCOMportDropDown.Value = {};
            app.SelectedCOMportDropDownLabel = uilabel(app.ConnectionButtonGroup);
            app.SelectedCOMportDropDownLabel.Text = 'Selected COM port';
            app.ConnectButton = uibutton(app.ConnectionButtonGroup, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            % Show the figure after all components are created
            app.HybridcomputerAPPmenuUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hybrid_computer_app_v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HybridcomputerAPPmenuUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HybridcomputerAPPmenuUIFigure)
        end
    end
end