classdef icblock_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ICBlock                   matlab.ui.Figure
        orwritevalueSpinner       matlab.ui.control.Spinner
        orwritevalueSpinnerLabel  matlab.ui.control.Label
        UnsetLamp                 matlab.ui.control.Lamp
        UnsetLampLabel            matlab.ui.control.Label
        SetButton                 matlab.ui.control.StateButton
        SetvalueSlider            matlab.ui.control.Slider
        SetvalueSliderLabel       matlab.ui.control.Label
        ICblockLabel              matlab.ui.control.Label
    end

    
    properties (Access = private)
        computer_port % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, port)
            app.computer_port = port;
        end

        % Value changed function: orwritevalueSpinner
        function orwritevalueSpinnerValueChanged(app, event)
            value = app.orwritevalueSpinner.Value;
            
            app.SetvalueSlider.Value = value;
            app.SetButton.Value = 0;
            app.UnsetLamp.Color = 'r';
            app.UnsetLampLabel.Text = 'Unset';
        end

        % Value changed function: SetvalueSlider
        function SetvalueSliderValueChanged(app, event)
            value = app.SetvalueSlider.Value;
            app.orwritevalueSpinner.Value = value;
            app.SetButton.Value = 0;
            app.UnsetLamp.Color = 'r';
            app.UnsetLampLabel.Text = 'Unset';
        end

        % Value changed function: SetButton
        function SetButtonValueChanged(app, event)
            value = app.SetButton.Value;
            
            if value == 1      
                writeline(app.computer_port,['IC,' num2str(app.orwritevalueSpinner.Value,'%.3f')]);
                resp = readline(app.computer_port);
                
                if isstring(resp) && ~contains(resp,"OK")
                    value = 0;
                    app.SetButton.Value = 0;
                    uialert(app.ICBlock,'Communication failed.','Warning');
                end
            end
            
            if value == 1      
                app.UnsetLamp.Color = 'g';
                app.UnsetLampLabel.Text = 'Set';
            else
                app.UnsetLamp.Color = 'r';
                app.UnsetLampLabel.Text = 'Unset';                    
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ICBlock = uifigure('Visible', 'off');
            app.ICBlock.Name = 'IC block';
            app.ICblockLabel = uilabel(app.ICBlock);
            app.ICblockLabel.Text = 'IC block';
            app.SetvalueSliderLabel = uilabel(app.ICBlock);
            app.SetvalueSliderLabel.Text = 'Set value';
            app.SetvalueSlider = uislider(app.ICBlock);
            app.SetvalueSlider.Limits = [-15 15];
            app.SetvalueSlider.ValueChangedFcn = createCallbackFcn(app, @SetvalueSliderValueChanged, true);
            app.SetButton = uibutton(app.ICBlock, 'state');
            app.SetButton.ValueChangedFcn = createCallbackFcn(app, @SetButtonValueChanged, true);
            app.SetButton.Text = 'Set';
            app.UnsetLampLabel = uilabel(app.ICBlock);
            app.UnsetLampLabel.Text = 'Unset';
            app.UnsetLamp = uilamp(app.ICBlock);
            app.orwritevalueSpinnerLabel = uilabel(app.ICBlock);
            app.orwritevalueSpinnerLabel.Text = 'or write value';
            app.orwritevalueSpinner = uispinner(app.ICBlock);
            app.orwritevalueSpinner.Step = 0.5;
            app.orwritevalueSpinner.Limits = [-15 15];
            app.orwritevalueSpinner.ValueChangedFcn = createCallbackFcn(app, @orwritevalueSpinnerValueChanged, true);
            % Show the figure after all components are created
            app.ICBlock.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = icblock_v1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ICBlock)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ICBlock)
        end
    end
end