classdef pwlblock_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PWLblockUIFigure             matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        NewYcoordinatesSpinner       matlab.ui.control.Spinner
        NewYcoordinatesSpinnerLabel  matlab.ui.control.Label
        NewXcoordinatesSpinner       matlab.ui.control.Spinner
        NewXcoordinatesSpinnerLabel  matlab.ui.control.Label
        ClearallButton               matlab.ui.control.Button
        ClearpointButton             matlab.ui.control.Button
        AddpointButton               matlab.ui.control.Button
        Table                        matlab.ui.control.Table
        SlopeSpinner                 matlab.ui.control.Spinner
        SlopeSpinnerLabel            matlab.ui.control.Label
        PWLfunctionDropDown          matlab.ui.control.DropDown
        PWLfunctionDropDownLabel     matlab.ui.control.Label
        SaveplotButtonGroup          matlab.ui.container.ButtonGroup
        LatexinterpreterCheckBox     matlab.ui.control.CheckBox
        GridsCheckBox                matlab.ui.control.CheckBox
        FormatDropDown               matlab.ui.control.DropDown
        FormatDropDownLabel          matlab.ui.control.Label
        SaveButton                   matlab.ui.control.Button
        UnsetLamp                    matlab.ui.control.Lamp
        UnsetLampLabel               matlab.ui.control.Label
        SetButton                    matlab.ui.control.StateButton
        RightPanel                   matlab.ui.container.Panel
        PWLblockLabel                matlab.ui.control.Label
        plotPWL                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        computer_port % Description
        plotdata % Description
    end
    
    methods (Access = private)
   
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, port)
            app.computer_port = port;
            
            app.plotPWL.XLim = [-15 15];
            app.plotPWL.YLim = [-15 15];
            
            app.plotPWL.XGrid = false;
            app.plotPWL.YGrid = false;
            
            app.plotdata = plot(app.plotPWL,0,0,'-*');
            
        end

        % Value changed function: SetButton
        function SetButtonValueChanged(app, event)
            value = app.SetButton.Value;
            
            if value == 1
                
                % UART
                
                app.UnsetLamp.Color = 'g';
                app.UnsetLampLabel.Text = 'Set';
            else
                app.UnsetLamp.Color = 'r';
                app.UnsetLampLabel.Text = 'Unset';                    
            end
        end

        % Value changed function: GridsCheckBox
        function GridsCheckBoxValueChanged(app, event)
            value = app.GridsCheckBox.Value;
            
            if value == 1
                            
                app.plotPWL.XGrid = true;
                app.plotPWL.YGrid = true;
                                
            elseif value == 0
                
                app.plotPWL.XGrid = false;
                app.plotPWL.YGrid = false;
                
            end
            
        end

        % Value changed function: LatexinterpreterCheckBox
        function LatexinterpreterCheckBoxValueChanged(app, event)
            value = app.LatexinterpreterCheckBox.Value;
            
            if value == 1
                            
                app.plotPWL.TickLabelInterpreter = "latex";
                app.plotPWL.Title.Interpreter = "latex";
                app.plotPWL.XLabel.Interpreter = "latex";
                app.plotPWL.YLabel.Interpreter = "latex";
                                
            elseif value == 0
                
                app.plotPWL.TickLabelInterpreter = "none";
                app.plotPWL.Title.Interpreter = "none";
                app.plotPWL.XLabel.Interpreter = "none";
                app.plotPWL.YLabel.Interpreter = "none";
                
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            selpath = uigetdir;
            
            if selpath ~= 0
                
                d = uiprogressdlg(app.PWLblockUIFigure,'Title','Saving','Indeterminate','on');
                drawnow
                
                value = app.FormatDropDown.Value;
                
                switch value
                    case 'PNG'
                        exportgraphics(app.plotPWL, [selpath '\'  date() '_pwl_function.png']);
                    case 'JPEG'
                        exportgraphics(app.plotXY, [selpath '\'  date() '_pwl_function.jpg']);
                    case 'Vect PDF'
                        exportgraphics(app.plotXY, [selpath '\'  date() '_pwl_function.pdf'],'ContentType','vector');
                    case 'M-Fig'

                    otherwise
                end
              
                close(d);
                
                uialert(app.PWLblockUIFigure,'Saved.',' ','Icon',"success");
            end
        end

        % Value changed function: PWLfunctionDropDown
        function PWLfunctionDropDownValueChanged(app, event)
            value = app.PWLfunctionDropDown.Value;
            
            switch value
                case 'Chua diode'
                    app.NewXcoordinatesSpinner.Visible = false;
                    app.NewYcoordinatesSpinner.Visible = false;
                    app.NewXcoordinatesSpinnerLabel.Visible = false;
                    app.NewYcoordinatesSpinnerLabel.Visible = false;
                    app.SlopeSpinnerLabel.Visible = false;
                    app.SlopeSpinner.Visible = false;
                    app.Table.Visible = false;
                    app.AddpointButton.Visible = false;
                    app.ClearpointButton.Visible = false;
                    app.ClearallButton.Visible = false;
                    
                case 'Symmetric Sine'
                    app.NewXcoordinatesSpinner.Visible = false;
                    app.NewYcoordinatesSpinner.Visible = false;
                    app.NewXcoordinatesSpinnerLabel.Visible = false;
                    app.NewYcoordinatesSpinnerLabel.Visible = false;
                    app.SlopeSpinnerLabel.Visible = false;
                    app.SlopeSpinner.Visible = false;
                    app.Table.Visible = false;
                    app.AddpointButton.Visible = false;
                    app.ClearpointButton.Visible = false;
                    app.ClearallButton.Visible = false;
                    
                case 'Symmetric Linear'
                    app.NewXcoordinatesSpinner.Visible = false;
                    app.NewYcoordinatesSpinner.Visible = false;
                    app.NewXcoordinatesSpinnerLabel.Visible = false;
                    app.NewYcoordinatesSpinnerLabel.Visible = false;
                    app.SlopeSpinnerLabel.Visible = true;
                    app.SlopeSpinner.Visible = true;
                    app.Table.Visible = false;
                    app.AddpointButton.Visible = false;
                    app.ClearpointButton.Visible = false;
                    app.ClearallButton.Visible = false;
                    
                case 'Custom'
                    app.NewXcoordinatesSpinner.Visible = true;
                    app.NewYcoordinatesSpinner.Visible = true;
                    app.NewXcoordinatesSpinnerLabel.Visible = true;
                    app.NewYcoordinatesSpinnerLabel.Visible = true;
                    app.SlopeSpinnerLabel.Visible = false;
                    app.SlopeSpinner.Visible = false;
                    app.Table.Visible = true;
                    app.AddpointButton.Visible = true;
                    app.ClearpointButton.Visible = true;
                    app.ClearallButton.Visible = true;
                    
                    app.Table.Data = table([-15;15],[-15;15]);
                    
                    dataFromTable = table2array(app.Table.Data);
                    app.plotdata = plot(app.plotPWL,dataFromTable(:,1),dataFromTable(:,2),'-*');
                    
                otherwise
                    
            end
            
        end

        % Button pushed function: AddpointButton
        function AddpointButtonPushed(app, event)
            newPoint = table(app.NewXcoordinatesSpinner.Value,app.NewYcoordinatesSpinner.Value);
            
            app.Table.Data = [app.Table.Data; newPoint];
            
            app.Table.Data = sortrows(app.Table.Data,1,'ascend');
            
            dataFromTable = table2array(app.Table.Data);
            app.plotdata = plot(app.plotPWL,dataFromTable(:,1),dataFromTable(:,2),'-*');
        end

        % Button pushed function: ClearpointButton
        function ClearpointButtonPushed(app, event)
            
        end

        % Button pushed function: ClearallButton
        function ClearallButtonPushed(app, event)
            app.Table.Data = table([-15;15],[-15;15]);
                    
            dataFromTable = table2array(app.Table.Data);
            app.plotdata = plot(app.plotPWL,dataFromTable(:,1),dataFromTable(:,2),'-*');
        end

        % Value changed function: SlopeSpinner
        function SlopeSpinnerValueChanged(app, event)
            value = app.SlopeSpinner.Value;
            
        end

        % Display data changed function: Table
        function TableDisplayDataChanged(app, event)
            dataFromTable = table2array(app.Table.Data);
            app.plotdata = plot(app.plotPWL,dataFromTable(:,1),dataFromTable(:,2),'-*');
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.PWLblockUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {816, 816};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {325, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PWLblockUIFigure = uifigure('Visible', 'off');
            app.PWLblockUIFigure.Name = 'PWL block';
            app.PWLblockUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.PWLblockUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SetButton = uibutton(app.LeftPanel, 'state');
            app.SetButton.ValueChangedFcn = createCallbackFcn(app, @SetButtonValueChanged, true);
            app.SetButton.Text = 'Set';
            app.UnsetLampLabel = uilabel(app.LeftPanel);
            app.UnsetLampLabel.Text = 'Unset';
            app.UnsetLamp = uilamp(app.LeftPanel);
            app.SaveplotButtonGroup = uibuttongroup(app.LeftPanel);
            app.SaveplotButtonGroup.Title = 'Save plot';
            app.SaveButton = uibutton(app.SaveplotButtonGroup, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.FormatDropDownLabel = uilabel(app.SaveplotButtonGroup);
            app.FormatDropDownLabel.Text = 'Format';
            app.FormatDropDown = uidropdown(app.SaveplotButtonGroup);
            app.FormatDropDown.Items = {'PNG', 'JPEG', 'Vect PDF', 'M-Fig'};
            app.FormatDropDown.Value = 'PNG';
            app.GridsCheckBox = uicheckbox(app.SaveplotButtonGroup);
            app.GridsCheckBox.ValueChangedFcn = createCallbackFcn(app, @GridsCheckBoxValueChanged, true);
            app.GridsCheckBox.Text = 'Grids';
            app.LatexinterpreterCheckBox = uicheckbox(app.SaveplotButtonGroup);
            app.LatexinterpreterCheckBox.ValueChangedFcn = createCallbackFcn(app, @LatexinterpreterCheckBoxValueChanged, true);
            app.LatexinterpreterCheckBox.Text = 'Latex interpreter';
            app.PWLfunctionDropDownLabel = uilabel(app.LeftPanel);
            app.PWLfunctionDropDownLabel.Text = 'PWL function';
            app.PWLfunctionDropDown = uidropdown(app.LeftPanel);
            app.PWLfunctionDropDown.Items = {'Chua diode', 'Symmetric Sine', 'Symmetric Linear', 'Custom'};
            app.PWLfunctionDropDown.ValueChangedFcn = createCallbackFcn(app, @PWLfunctionDropDownValueChanged, true);
            app.PWLfunctionDropDown.Value = 'Chua diode';
            app.SlopeSpinnerLabel = uilabel(app.LeftPanel);
            app.SlopeSpinnerLabel.Visible = 'off';
            app.SlopeSpinnerLabel.Text = 'Slope';
            app.SlopeSpinner = uispinner(app.LeftPanel);
            app.SlopeSpinner.Step = 0.02;
            app.SlopeSpinner.Limits = [0 5];
            app.SlopeSpinner.ValueDisplayFormat = '%1.3f';
            app.SlopeSpinner.ValueChangedFcn = createCallbackFcn(app, @SlopeSpinnerValueChanged, true);
            app.SlopeSpinner.Visible = 'off';
            app.Table = uitable(app.LeftPanel);
            app.Table.ColumnName = {'X coordinates'; 'Y coordinates'};
            app.Table.RowName = {};
            app.Table.ColumnEditable = true;
            app.Table.DisplayDataChangedFcn = createCallbackFcn(app, @TableDisplayDataChanged, true);
            app.Table.Visible = 'off';
            app.AddpointButton = uibutton(app.LeftPanel, 'push');
            app.AddpointButton.ButtonPushedFcn = createCallbackFcn(app, @AddpointButtonPushed, true);
            app.AddpointButton.Visible = 'off';
            app.AddpointButton.Text = 'Add point';
            app.ClearpointButton = uibutton(app.LeftPanel, 'push');
            app.ClearpointButton.ButtonPushedFcn = createCallbackFcn(app, @ClearpointButtonPushed, true);
            app.ClearpointButton.Visible = 'off';
            app.ClearpointButton.Text = 'Clear point';
            app.ClearallButton = uibutton(app.LeftPanel, 'push');
            app.ClearallButton.ButtonPushedFcn = createCallbackFcn(app, @ClearallButtonPushed, true);
            app.ClearallButton.Visible = 'off';
            app.ClearallButton.Text = 'Clear all';
            app.NewXcoordinatesSpinnerLabel = uilabel(app.LeftPanel);
            app.NewXcoordinatesSpinnerLabel.Visible = 'off';
            app.NewXcoordinatesSpinnerLabel.Text = 'New X coordinates';
            app.NewXcoordinatesSpinner = uispinner(app.LeftPanel);
            app.NewXcoordinatesSpinner.Step = 0.5;
            app.NewXcoordinatesSpinner.Limits = [-15 15];
            app.NewXcoordinatesSpinner.Visible = 'off';
            app.NewYcoordinatesSpinnerLabel = uilabel(app.LeftPanel);
            app.NewYcoordinatesSpinnerLabel.Visible = 'off';
            app.NewYcoordinatesSpinnerLabel.Text = 'New Y coordinates';
            app.NewYcoordinatesSpinner = uispinner(app.LeftPanel);
            app.NewYcoordinatesSpinner.Step = 0.5;
            app.NewYcoordinatesSpinner.Limits = [-15 15];
            app.NewYcoordinatesSpinner.Visible = 'off';
            app.RightPanel = uipanel(app.GridLayout);
            app.plotPWL = uiaxes(app.RightPanel);
            xlabel(app.plotPWL, 'In [V]')
            ylabel(app.plotPWL, 'Out [V]')
            app.PWLblockLabel = uilabel(app.RightPanel);
            app.PWLblockLabel.Text = 'PWL block';
            % Show the figure after all components are created
            app.PWLblockUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pwlblock_v1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PWLblockUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PWLblockUIFigure)
        end
    end
end