classdef recording_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RecordingUIFigure           matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        SamplingfrequencyHzSpinner  matlab.ui.control.Spinner
        SamplingfrequencyHzSpinnerLabel  matlab.ui.control.Label
        SavedataButtonGroup         matlab.ui.container.ButtonGroup
        FormatDropDown_2            matlab.ui.control.DropDown
        FormatDropDown_2Label       matlab.ui.control.Label
        SavedataButton              matlab.ui.control.Button
        SaveplotsButtonGroup        matlab.ui.container.ButtonGroup
        FormatDropDown              matlab.ui.control.DropDown
        FormatDropDownLabel         matlab.ui.control.Label
        SavescreensButton           matlab.ui.control.Button
        LatexinterpreterCheckBox    matlab.ui.control.CheckBox
        GridsCheckBox               matlab.ui.control.CheckBox
        ClearplotsButton            matlab.ui.control.Button
        StoprecordingButton         matlab.ui.control.Button
        StartrecordingButton        matlab.ui.control.Button
        RightPanel                  matlab.ui.container.Panel
        ZWLabel                     matlab.ui.control.Label
        YWLabel                     matlab.ui.control.Label
        YZLabel                     matlab.ui.control.Label
        XWLabel                     matlab.ui.control.Label
        XZLabel                     matlab.ui.control.Label
        XYLabel                     matlab.ui.control.Label
        RecordingLabel              matlab.ui.control.Label
        plotYZ                      matlab.ui.control.UIAxes
        plotZW                      matlab.ui.control.UIAxes
        plotYW                      matlab.ui.control.UIAxes
        plotXW                      matlab.ui.control.UIAxes
        plotXZ                      matlab.ui.control.UIAxes
        plotXY                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        recording % Description
        timer % Description
        XYdata % Description
        XZdata % Description
        XWdata % Description
        YZdata % Description
        YWdata % Description
        ZWdata % Description
        computer_port % Description
    end
    
    methods (Access = private)
           
        function SerialRecieveCallback(app,~,~)
            
            receivedData = readline(app.computer_port);
            
            if isstring(receivedData)

                receivedData = split(receivedData,',');
                
                adx = str2double(receivedData(1))*(10 / 4096);
                ady = str2double(receivedData(2))*(10 / 4096);
                adz = str2double(receivedData(3))*(10 / 4096);
                adw = str2double(receivedData(4))*(10 / 4096);
            
                % Update XY in plot
                app.XYdata.XData = [app.XYdata.XData adx];
                app.XYdata.YData = [app.XYdata.YData ady];
            
                % Update XZ in plot
                app.XZdata.XData = [app.XZdata.XData adx];
                app.XZdata.YData = [app.XZdata.YData adz];
            
                % Update XW in plot
                app.XWdata.XData = [app.XWdata.XData adx];
                app.XWdata.YData = [app.XWdata.YData adw];
            
                % Update YZ in plot
                app.YZdata.XData = [app.YZdata.XData ady];
                app.YZdata.YData = [app.YZdata.YData adz];
            
                % Update YW in plot
                app.YWdata.XData = [app.YWdata.XData ady];
                app.YWdata.YData = [app.YWdata.YData adw];
            
                % Update ZW in plot
                app.ZWdata.XData = [app.ZWdata.XData adz];
                app.ZWdata.YData = [app.ZWdata.YData adw];    
            
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, port)
            app.computer_port = port;
            app.recording = false;
            
            %app.timer = timer('ExecutionMode', 'fixedRate', 'Period', 500e-3,'BusyMode', 'queue', 'TimerFcn', @app.TimerFcn);
            
            app.plotXY.XLim = [-15 15];
            app.plotXY.YLim = [-15 15];
            
            app.plotXZ.XLim = [-15 15];
            app.plotXZ.YLim = [-15 15];
            
            app.plotXW.XLim = [-15 15];
            app.plotXW.YLim = [-15 15];
            
            app.plotYZ.XLim = [-15 15];
            app.plotYZ.YLim = [-15 15];
            
            app.plotYW.XLim = [-15 15];
            app.plotYW.YLim = [-15 15];
            
            app.plotZW.XLim = [-15 15];
            app.plotZW.YLim = [-15 15];
            
            app.XYdata = plot(app.plotXY,0,0);
            app.XZdata = plot(app.plotXZ,0,0);
            app.XWdata = plot(app.plotXW,0,0);
            app.YZdata = plot(app.plotYZ,0,0);
            app.YWdata = plot(app.plotYW,0,0);
            app.ZWdata = plot(app.plotZW,0,0);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.RecordingUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {816, 816};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {160, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: LatexinterpreterCheckBox
        function LatexinterpreterCheckBoxValueChanged(app, event)
            
            value = app.LatexinterpreterCheckBox.Value;
            
            if value == 1
                            
                app.plotXY.TickLabelInterpreter = "latex";
                app.plotXY.Title.Interpreter = "latex";
                app.plotXY.XLabel.Interpreter = "latex";
                app.plotXY.YLabel.Interpreter = "latex";
                
                app.plotXZ.TickLabelInterpreter = "latex";
                app.plotXZ.Title.Interpreter = "latex";
                app.plotXZ.XLabel.Interpreter = "latex";
                app.plotXZ.YLabel.Interpreter = "latex";
                
                app.plotXW.TickLabelInterpreter = "latex";
                app.plotXW.Title.Interpreter = "latex";
                app.plotXW.XLabel.Interpreter = "latex";
                app.plotXW.YLabel.Interpreter = "latex";
                
                app.plotYZ.TickLabelInterpreter = "latex";
                app.plotYZ.Title.Interpreter = "latex";
                app.plotYZ.XLabel.Interpreter = "latex";
                app.plotYZ.YLabel.Interpreter = "latex";
                
                app.plotYW.TickLabelInterpreter = "latex";
                app.plotYW.Title.Interpreter = "latex";
                app.plotYW.XLabel.Interpreter = "latex";
                app.plotYW.YLabel.Interpreter = "latex";
                
                app.plotZW.TickLabelInterpreter = "latex";
                app.plotZW.Title.Interpreter = "latex";
                app.plotZW.XLabel.Interpreter = "latex";
                app.plotZW.YLabel.Interpreter = "latex";
                
            elseif value == 0
                
                app.plotXY.TickLabelInterpreter = "none";
                app.plotXY.Title.Interpreter = "none";
                app.plotXY.XLabel.Interpreter = "none";
                app.plotXY.YLabel.Interpreter = "none";
                
                app.plotXZ.TickLabelInterpreter = "none";
                app.plotXZ.Title.Interpreter = "none";
                app.plotXZ.XLabel.Interpreter = "none";
                app.plotXZ.YLabel.Interpreter = "none";
                
                app.plotXW.TickLabelInterpreter = "none";
                app.plotXW.Title.Interpreter = "none";
                app.plotXW.XLabel.Interpreter = "none";
                app.plotXW.YLabel.Interpreter = "none";
                
                app.plotYZ.TickLabelInterpreter = "none";
                app.plotYZ.Title.Interpreter = "none";
                app.plotYZ.XLabel.Interpreter = "none";
                app.plotYZ.YLabel.Interpreter = "none";
                
                app.plotYW.TickLabelInterpreter = "none";
                app.plotYW.Title.Interpreter = "none";
                app.plotYW.XLabel.Interpreter = "none";
                app.plotYW.YLabel.Interpreter = "none";
                
                app.plotZW.TickLabelInterpreter = "none";
                app.plotZW.Title.Interpreter = "none";
                app.plotZW.XLabel.Interpreter = "none";
                app.plotZW.YLabel.Interpreter = "none";
                
            end
        end

        % Value changed function: GridsCheckBox
        function GridsCheckBoxValueChanged(app, event)
            value = app.GridsCheckBox.Value;
            
            if value == 1
                
                app.plotXY.XGrid = true;
                app.plotXY.YGrid = true;
                app.plotXZ.XGrid = true;
                app.plotXZ.YGrid = true;
                app.plotXW.XGrid = true;
                app.plotXW.YGrid = true;
                app.plotYZ.XGrid = true;
                app.plotYZ.YGrid = true;
                app.plotYW.XGrid = true;
                app.plotYW.YGrid = true;
                app.plotZW.XGrid = true;
                app.plotZW.YGrid = true;
                
            elseif value == 0
               
                app.plotXY.XGrid = false;
                app.plotXY.YGrid = false;
                app.plotXZ.XGrid = false;
                app.plotXZ.YGrid = false;
                app.plotXW.XGrid = false;
                app.plotXW.YGrid = false;
                app.plotYZ.XGrid = false;
                app.plotYZ.YGrid = false;
                app.plotYW.XGrid = false;
                app.plotYW.YGrid = false;
                app.plotZW.XGrid = false;
                app.plotZW.YGrid = false;
                
            end
            
        end

        % Button pushed function: StartrecordingButton
        function StartrecordingButtonPushed(app, event)
            if app.recording == false
                
                writeline(app.computer_port, ['RECSTART,' app.SamplingfrequencyHzSpinner.Value]);
                resp = readline(app.computer_port);

                if isstring(resp) && contains(resp,"OK")
                    configureCallback(app.computer_port,"terminator",@app.SerialRecieveCallback);
                    app.recording = true;
                else
                    uialert(app.RecordingUIFigure,'Communication failed.','Warning');
                end
            end
        end

        % Button pushed function: StoprecordingButton
        function StoprecordingButtonPushed(app, event)
            if app.recording == true
                
                configureCallback(app.computer_port,"off");
                
                writeline(app.computer_port,'RECSTOP');
            end                
        end

        % Button pushed function: ClearplotsButton
        function ClearplotsButtonPushed(app, event)
            app.XYdata.XData = app.XYdata.XData(end);
            app.XYdata.YData = app.XYdata.YData(end);
            
            app.XZdata.XData = app.XZdata.XData(end);
            app.XZdata.YData = app.XYdata.YData(end);
            
            app.XWdata.XData = app.XWdata.XData(end);
            app.XWdata.YData = app.XWdata.YData(end);
            
            app.YZdata.XData = app.YZdata.XData(end);
            app.YZdata.YData = app.YZdata.YData(end);
            
            app.YWdata.XData = app.YWdata.XData(end);
            app.YWdata.YData = app.YWdata.YData(end);
            
            app.ZWdata.XData = app.ZWdata.XData(end);
            app.ZWdata.YData = app.ZWdata.YData(end);
        end

        % Button pushed function: SavescreensButton
        function SavescreensButtonPushed(app, event)
            selpath = uigetdir;
            
            if selpath ~= 0
                
                d = uiprogressdlg(app.RecordingUIFigure,'Title','Saving','Indeterminate','on');
                drawnow
                
                value = app.FormatDropDown.Value;
                
                switch value
                    case 'PNG'
                        exportgraphics(app.plotXY, [selpath '\'  date() '_XY.png']);
                        exportgraphics(app.plotXZ, [selpath '\'  date() '_XZ.png']);
                        exportgraphics(app.plotXW, [selpath '\'  date() '_XW.png']);
                        exportgraphics(app.plotYZ, [selpath '\'  date() '_YZ.png']);
                        exportgraphics(app.plotYW, [selpath '\'  date() '_YW.png']);
                        exportgraphics(app.plotZW, [selpath '\'  date() '_ZW.png']);   
                    case 'JPEG'
                        exportgraphics(app.plotXY, [selpath '\'  date() '_XY.jpg']);
                        exportgraphics(app.plotXZ, [selpath '\'  date() '_XZ.jpg']);
                        exportgraphics(app.plotXW, [selpath '\'  date() '_XW.jpg']);
                        exportgraphics(app.plotYZ, [selpath '\'  date() '_YZ.jpg']);
                        exportgraphics(app.plotYW, [selpath '\'  date() '_YW.jpg']);
                        exportgraphics(app.plotZW, [selpath '\'  date() '_ZW.jpg']);
                    case 'Vect PDF'
                        exportgraphics(app.plotXY, [selpath '\'  date() '_XY.pdf'],'ContentType','vector');
                        exportgraphics(app.plotXZ, [selpath '\'  date() '_XZ.pdf'],'ContentType','vector');
                        exportgraphics(app.plotXW, [selpath '\'  date() '_XW.pdf'],'ContentType','vector');
                        exportgraphics(app.plotYZ, [selpath '\'  date() '_YZ.pdf'],'ContentType','vector');
                        exportgraphics(app.plotYW, [selpath '\'  date() '_YW.pdf'],'ContentType','vector');
                        exportgraphics(app.plotZW, [selpath '\'  date() '_ZW.pdf'],'ContentType','vector');
                    case 'M-Fig'

                    otherwise
                end
              
                close(d);
                
                uialert(app.RecordingUIFigure,'Saved.',' ','Icon',"success");
            end
            
        end

        % Button pushed function: SavedataButton
        function SavedataButtonPushed(app, event)
            
            selpath = uigetdir;
            
            if selpath ~= 0
                
                d = uiprogressdlg(app.RecordingUIFigure,'Title','Saving','Indeterminate','on');
                drawnow
                
                xdata = app.XYdata.XData';
                ydata = app.XYdata.YData';
                zdata = app.ZWdata.XData';
                wdata = app.ZWdata.YData';
           
                data = [xdata ydata zdata wdata];
                
                value = app.FormatDropDown_2.Value;
                
                switch value
                    case 'CSV'
                        writematrix(data,[selpath '\'  date() '_data.csv'],"Delimiter",';');
                    case 'TXT'
                        writematrix(data,[selpath '\'  date() '_data.txt'],"Delimiter",';');    
                    case 'XLS'
                        writematrix(data,[selpath '\'  date() '_data.xls']);        
                    case 'MAT'
                        save([selpath '\'  date() '_data.mat'],'data');
                    otherwise
                end
           
                close(d);
                
                uialert(app.RecordingUIFigure,'Saved.',' ','Icon',"success");
            end
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RecordingUIFigure = uifigure('Visible', 'off');
            app.RecordingUIFigure.Name = 'Recording';
            app.RecordingUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.RecordingUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.StartrecordingButton = uibutton(app.LeftPanel, 'push');
            app.StartrecordingButton.ButtonPushedFcn = createCallbackFcn(app, @StartrecordingButtonPushed, true);
            app.StartrecordingButton.Text = {'Start'; 'recording'};
            app.StoprecordingButton = uibutton(app.LeftPanel, 'push');
            app.StoprecordingButton.ButtonPushedFcn = createCallbackFcn(app, @StoprecordingButtonPushed, true);
            app.StoprecordingButton.Text = {'Stop'; 'recording'};
            app.ClearplotsButton = uibutton(app.LeftPanel, 'push');
            app.ClearplotsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearplotsButtonPushed, true);
            app.ClearplotsButton.Text = {'Clear'; 'plots'};
            app.GridsCheckBox = uicheckbox(app.LeftPanel);
            app.GridsCheckBox.ValueChangedFcn = createCallbackFcn(app, @GridsCheckBoxValueChanged, true);
            app.GridsCheckBox.Text = 'Grids';
            app.LatexinterpreterCheckBox = uicheckbox(app.LeftPanel);
            app.LatexinterpreterCheckBox.ValueChangedFcn = createCallbackFcn(app, @LatexinterpreterCheckBoxValueChanged, true);
            app.LatexinterpreterCheckBox.Text = 'Latex interpreter';
            app.SaveplotsButtonGroup = uibuttongroup(app.LeftPanel);
            app.SaveplotsButtonGroup.Title = 'Save plots';
            app.SavescreensButton = uibutton(app.SaveplotsButtonGroup, 'push');
            app.SavescreensButton.ButtonPushedFcn = createCallbackFcn(app, @SavescreensButtonPushed, true);
            app.SavescreensButton.Text = {'Save'; 'screens'};
            app.FormatDropDownLabel = uilabel(app.SaveplotsButtonGroup);
            app.FormatDropDownLabel.Text = 'Format';
            app.FormatDropDown = uidropdown(app.SaveplotsButtonGroup);
            app.FormatDropDown.Items = {'PNG', 'JPEG', 'Vect PDF', 'M-Fig'};
            app.FormatDropDown.Value = 'PNG';
            app.SavedataButtonGroup = uibuttongroup(app.LeftPanel);
            app.SavedataButtonGroup.Title = 'Save data';
            app.SavedataButton = uibutton(app.SavedataButtonGroup, 'push');
            app.SavedataButton.ButtonPushedFcn = createCallbackFcn(app, @SavedataButtonPushed, true);
            app.SavedataButton.Text = {'Save'; 'data'};
            app.FormatDropDown_2Label = uilabel(app.SavedataButtonGroup);
            app.FormatDropDown_2Label.Text = 'Format';
            app.FormatDropDown_2 = uidropdown(app.SavedataButtonGroup);
            app.FormatDropDown_2.Items = {'CSV', 'TXT', 'XLS', 'MAT'};
            app.FormatDropDown_2.Value = 'CSV';
            app.SamplingfrequencyHzSpinnerLabel = uilabel(app.LeftPanel);
            app.SamplingfrequencyHzSpinnerLabel.Text = 'Sampling frequency [Hz]';
            app.SamplingfrequencyHzSpinner = uispinner(app.LeftPanel);
            app.SamplingfrequencyHzSpinner.Limits = [1 50000];
            app.SamplingfrequencyHzSpinner.RoundFractionalValues = 'on';
            app.SamplingfrequencyHzSpinner.Value = 1000;
            app.RightPanel = uipanel(app.GridLayout);
            app.plotXY = uiaxes(app.RightPanel);
            xlabel(app.plotXY, 'X [V]')
            ylabel(app.plotXY, 'Y [V]')
            zlabel(app.plotXY, 'Z')
            app.plotXZ = uiaxes(app.RightPanel);
            xlabel(app.plotXZ, 'X [V]')
            ylabel(app.plotXZ, 'Z [V]')
            zlabel(app.plotXZ, 'Z')
            app.plotXW = uiaxes(app.RightPanel);
            xlabel(app.plotXW, 'X [V]')
            ylabel(app.plotXW, 'W [V]')
            zlabel(app.plotXW, 'Z')
            app.plotYW = uiaxes(app.RightPanel);
            xlabel(app.plotYW, 'Y [V]')
            ylabel(app.plotYW, 'W [V]')
            zlabel(app.plotYW, 'Z')
            app.plotZW = uiaxes(app.RightPanel);
            xlabel(app.plotZW, 'Z [V]')
            ylabel(app.plotZW, 'W [V]')
            zlabel(app.plotZW, 'Z')
            app.plotYZ = uiaxes(app.RightPanel);
            xlabel(app.plotYZ, 'Y [V]')
            ylabel(app.plotYZ, 'Z [V]')
            zlabel(app.plotYZ, 'Z')
            app.RecordingLabel = uilabel(app.RightPanel);
            app.RecordingLabel.Text = 'Recording';
            app.XYLabel = uilabel(app.RightPanel);
            app.XYLabel.Text = 'X-Y';
            app.XZLabel = uilabel(app.RightPanel);
            app.XZLabel.Text = 'X-Z';
            app.XWLabel = uilabel(app.RightPanel);
            app.XWLabel.Text = 'X-W';
            app.YZLabel = uilabel(app.RightPanel);
            app.YZLabel.Text = 'Y-Z';
            app.YWLabel = uilabel(app.RightPanel);
            app.YWLabel.Text = 'Y-W';
            app.ZWLabel = uilabel(app.RightPanel);
            app.ZWLabel.Text = 'Z-W';
            % Show the figure after all components are created
            app.RecordingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = recording_v1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RecordingUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RecordingUIFigure)
        end
    end
end